classdef FilamentWinding < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MachoWinderXFilamentWindingSoftwareLabel  matlab.ui.control.Label
        AnimatePlotButton               matlab.ui.control.Button
        WindAngleEditField              matlab.ui.control.NumericEditField
        WindAngleEditFieldLabel         matlab.ui.control.Label
        PathsDropDownLabel_2            matlab.ui.control.Label
        PathsDropDown                   matlab.ui.control.DropDown
        PathsDropDownLabel              matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        CalculateButton                 matlab.ui.control.Button
        ProgressSlider                  matlab.ui.control.Slider
        LayersPanel                     matlab.ui.container.Panel
        MoveDownButton                  matlab.ui.control.Button
        MoveUpButton                    matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        ExportButton                    matlab.ui.control.Button
        ClearLayerButton                matlab.ui.control.Button
        AddLayerButton                  matlab.ui.control.Button
        FilamentPropertiesPanel         matlab.ui.container.Panel
        FilamentWidthEditField          matlab.ui.control.NumericEditField
        FilamentWidthEditFieldLabel     matlab.ui.control.Label
        FilamentStrengthEditField       matlab.ui.control.NumericEditField
        FilamentStrengthEditFieldLabel  matlab.ui.control.Label
        FilamentThicknessEditField      matlab.ui.control.NumericEditField
        FilamentThicknessEditFieldLabel  matlab.ui.control.Label
        DesignParametersPanel           matlab.ui.container.Panel
        FrictionFactorEditField         matlab.ui.control.NumericEditField
        FrictionFactorEditFieldLabel    matlab.ui.control.Label
        PressureEditField               matlab.ui.control.NumericEditField
        PressureEditFieldLabel          matlab.ui.control.Label
        SafetyFactorEditField           matlab.ui.control.NumericEditField
        SafetyFactorEditFieldLabel      matlab.ui.control.Label
        VesselGeomteryPanel             matlab.ui.container.Panel
        CylinderLengthEditField         matlab.ui.control.NumericEditField
        CylinderLengthEditFieldLabel    matlab.ui.control.Label
        InletDiameterEditField          matlab.ui.control.NumericEditField
        InletDiameterEditFieldLabel     matlab.ui.control.Label
        DiameterEditField               matlab.ui.control.NumericEditField
        DiameterEditFieldLabel          matlab.ui.control.Label
        WindAngleSlider                 matlab.ui.control.Slider
        MoldShapeButtonGroup            matlab.ui.container.ButtonGroup
        CylinderwithCapsButton          matlab.ui.control.RadioButton
        CylinderButton                  matlab.ui.control.RadioButton
        SphereButton                    matlab.ui.control.RadioButton
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        polar_alpha %polar angle (radians)
        S_F %fiber strength
        P %pressure
        N %safety factor
        D % Diameter
        R %Radius
        w %filament width
        t %filament thickness
        r_0 %starting radius
        d_0 %starting diameter
        n_pts %number of points
        ff %friction factor
        g % g-code
        G % surface mold array
        alpha_helical % helical wind angle
        cyl_Length %cylinder Length
        i %index

        alpha_list %array used in netting analysis
        t_helical %thikness array for helical layers
        t_hoop %thickness array for hoop layers
        n_helical %number array for helical layers
        n_hoop %number array for hoop layers
        selected_layer %layer selected
        g_curr %selected wind angle'
       
        lay_ang

        cycle
        circuit
        layer 

        begin_hoop
        hoop_layer

    end
    
    methods (Access = private)
        
        function Netting_Analysis(app)
                toRad = pi / 180;
                S_D = app.S_F / app.N; %design strength
                N_hoop = app.P * app.R; %hoop force
                N_axial = N_hoop / 2; %axial force

            if app.SphereButton.Value
                app.t_helical = N_axial / (S_D * cos(app.polar_alpha)^2); %hoop thickness
                app.t_hoop = (N_hoop - N_axial * tan(app.polar_alpha)^2) / S_D; %helical thickness
            else
                max_alpha = 54;
                app.alpha_list = linspace(1,max_alpha, 54); %local alpha variable
                len = 54;

                app.t_helical = zeros(1,len); %hoop thickness
                app.t_hoop = zeros(1,len); %helical thickness

                for k = 1:len
                    app.t_helical(k) = N_axial / (S_D * cos(app.alpha_list(k)*toRad)^2); %hoop thickness
                    app.t_hoop(k) = (N_hoop - N_axial * tan(app.alpha_list(k)*toRad)^2) / S_D; %helical thickness
                end
            end

            app.n_hoop = ceil(app.t_hoop / app.t); %returns number of hoop wraps
            app.n_helical = ceil(app.t_helical / app.t); %returns number of helix wraps

        end
        
        function plot3D(app) %plots the line segment (no width for filament) - plot web shows the filament width
            cir = app.g_curr{1,1};
            cyc = app.g_curr{1,2};
            lay = app.g_curr{1,3};

            cir_len = length(cir);
            cyc_len = length(cyc);
            lay_len = length(lay);
            [cir_i, cyc_i, lay_i] = map(app, cir_len, cyc_len, lay_len);

            path = app.PathsDropDown.Value;
            
            cla(app.UIAxes, 'reset')


            switch path
                case 'Circuit'
                    plot3(app.UIAxes, cir(1,1:cir_i), cir(2,1:cir_i), cir(3,1:cir_i)) %plots x,y,z filament
                case 'Cycle'
                    plot3(app.UIAxes, cyc(1,1:cyc_i), cyc(2,1:cyc_i), cyc(3,1:cyc_i)) %plots x,y,z filament
                case 'Layer'
                    plot3(app.UIAxes, lay(1,1:lay_i), lay(2,1:lay_i), lay(3,1:lay_i)) %plots x,y,z filament
            end
            axis(app.UIAxes, 'equal');
            if app.SphereButton.Value
                xlim(app.UIAxes, [0, app.D])
            else
                xlim(app.UIAxes, [0, app.cyl_Length + app.D])
            end
            ylim(app.UIAxes, [-app.R, app.R])
            zlim(app.UIAxes, [-app.R, app.R])
            
        end
        
        function plot_Net_Analysis(app)
            cla(app.UIAxes, 'reset') %clears plot
            
            hold(app.UIAxes, "all")
            yyaxis(app.UIAxes, "left")
            plot(app.UIAxes, app.alpha_list, app.n_helical);
            plot(app.UIAxes, app.alpha_list, app.n_hoop);
            ylabel(app.UIAxes,"Number of Layers", 'Color', 'black', 'FontName', 'Calibri')
            ylim(app.UIAxes, [0, app.n_helical(end) + 1])

            yyaxis(app.UIAxes, "right")
            plot(app.UIAxes, app.alpha_list, app.t_helical);
            plot(app.UIAxes, app.alpha_list, app.t_hoop);
            ylabel(app.UIAxes, "Thickness (in)", 'Color', 'black', 'FontName', 'Calibri')

            xlabel(app.UIAxes, "Wind Angle (degrees)", 'Color', 'black', 'FontName', 'Calibri')
            xlim(app.UIAxes, [0, app.alpha_list(end)])

            title(app.UIAxes, "Helical and Hoop Layers v.s Wind Angles", 'Color', 'black', 'FontName', 'Calibri')
            legend(app.UIAxes, 'Helical layers', 'Hoop layers', 'Helical thickness', 'Hoop thickness', 'Location', 'northwest')
            hold(app.UIAxes, "off")

        end
        
        function pop_tab(app)
 
            app.UITable.Data = {};
            
            if app.SphereButton.Value
                helical = app.n_helical;
                wind_angle = app.polar_alpha*180/pi;
                for k = 1: helical %add helical winds to table
                    new_data = {wind_angle};
                    app.UITable.Data = [app.UITable.Data; new_data];
                end
            else
                wind_angle = app.alpha_list(app.selected_layer);
                hoop = app.n_hoop(app.selected_layer);
                helical = app.n_helical(app.selected_layer);
                for k = 1:hoop %add hoop winds to table
                    new_data = {90};
                    app.UITable.Data = [app.UITable.Data; new_data];
                end

                for k = 1: helical %add helical winds to table
                    new_data = {wind_angle};
                    app.UITable.Data = [app.UITable.Data; new_data];
                end
            end
        end


        function [forward, backward, deg_revolutions] = helical_wind(app, alpha_helical)
            
            pitch = 2*app.D / tan(alpha_helical); % (6)
            revolutions = app.cyl_Length / pitch; % (7)
            deg_revolutions = revolutions * 360;

            rad_revolutions = deg_revolutions * pi / 180;
            phi_right_back = (270 + deg_revolutions) * pi / 180;

            %forward
            phi_cyl = linspace(pi/2, rad_revolutions + pi/2, app.n_pts); % (9)
            x_cyl = linspace(app.R, app.R + app.cyl_Length, app.n_pts);
            y_cyl = app.R*cos(phi_cyl); % (11)
            z_cyl = app.R*sin(phi_cyl); % (12)

            %backward
            phi_cyl_back = linspace(phi_right_back, phi_right_back + rad_revolutions, app.n_pts);
            x_cyl_back = fliplr(x_cyl);
            y_cyl_back = app.R*cos(phi_cyl_back); % (11)
            z_cyl_back = app.R*sin(phi_cyl_back); % (12)

            forward = [x_cyl; y_cyl; z_cyl];
            backward = [x_cyl_back; y_cyl_back; z_cyl_back];
        end


        function [left, right] = polar_wind(app)
            l_0 = app.R*cos(app.polar_alpha); %(-2)
            x_0 = app.R - l_0; % (-1)

            x_left = linspace(x_0, app.R, app.n_pts); % (0)
            y_left = zeros(1, app.n_pts);
            z_left = zeros(1, app.n_pts);
            phi_left = zeros(1, app.n_pts);


            %Left polar winding
            for j = 1:app.n_pts %plotting points for the left hemispherical end cap
                l = app.R - x_left(j); % (1)
                y_left(j) = l * tan(app.polar_alpha); % (2)
                r_xy = sqrt(l^2 + y_left(j)); % (3)
                z_left(j) = sqrt(app.R^2 - r_xy^2); % (4)
                phi_left(j) = atan(z_left(j)/y_left(j)); % (5)
            end

            x_left_back = fliplr(x_left);
            y_left_back = fliplr(y_left);
            z_left_back = fliplr(z_left .* -1);

            left = [x_left, x_left_back;
                y_left, y_left_back;
                z_left, z_left_back];

            %Right polar winding
            x_start = app.R + app.cyl_Length; % (14)
            x_right = linspace(x_start, x_start + l_0, app.n_pts); % (15)
            y_right = zeros(1,app.n_pts);
            z_right = fliplr(z_left); %z right is just z left but flipper

            l = zeros(1,app.n_pts);
            r_xy = zeros(1,app.n_pts);

            for j = 1:app.n_pts %plotting points for the right hemispherical end cap
                l(j) = x_right(j) - x_start; % (15)
                y_right(j) = -l(j) * tan(app.polar_alpha); % (16)
                r_xy(j) = sqrt(l(j)^2 + y_right(j)^2); % (3)
            end

            z_right_back = fliplr(z_right .* -1);
            y_right_back = flip(y_right);
            x_right_back = fliplr(x_right);

            right = [x_right, x_right_back;
                y_right, y_right_back;
                z_right, z_right_back];
           %{ 
            extend_right = x_right(end) + 1; %add 1 inch to the end

            app.right_g = [x_right, extend_right, x_right_back;
                       y_right,  y_right(end), y_right_back;
                       z_right, z_right(end), z_right_back];

            extend_left = x_left(end) - 1;
            
           app.left_g = [x_left, extend_left, x_left_back;
                       y_left,  y_left(end), y_left_back;
                       z_left, z_left(end), z_left_back];
           %}
           %lay_angle = zeros(1,app.npts);
           %app.lay_angle = [lay_angle, 180, lay_angle];

            left=real(left);
            right=real(right);
        end
        
        function [cir, deg_revolutions] = circuit_Generator(app, helical_angle)
            [left, right] = polar_wind(app);
            [forward, backwards, deg_revolutions] = helical_wind(app, helical_angle);

            left_start = [left(1,1:app.n_pts); left(2,1:app.n_pts); left(3,1:app.n_pts)];
            left_end = [left(1,app.n_pts+1:end); left(2,app.n_pts+1:end); left(3,app.n_pts+1:end)];

            right_start = [right(1,1:app.n_pts); right(2,1:app.n_pts); right(3,1:app.n_pts)];
            right_end = [right(1,app.n_pts+1:end); right(2,app.n_pts+1:end); right(3,app.n_pts+1:end)];
            
            over = 0; %adjust this value to increase step size

            if app.SphereButton.Value
                cir = [left_start, right, left_end];

                extend_right = right_start(:,end); %pulls last 3 coordinates from array
                extend_left = left_end(:,end);

                %right side
                extend_right(1,1) = extend_right(1,1) + over; %adjust x coordinates

                %left side
                extend_left(1,1) = extend_left(1,1) - over; %adjust x coordinates

                app.circuit = [left_start, right_start, extend_right, right_end(:,2:app.n_pts), left_end, extend_left]; %reserved for g-code
            else
                %rotate right hemispherical endcap by one helical revolution (forward)
                Rotate = rotx(deg_revolutions);
                right_rot = Rotate * right;

                right_start_rot = Rotate * right_start;
                right_end_rot = Rotate * right_end;

                %rotate left hemispherical endcap by two helical revolutions (forward and
                %backwards)
                Rotate2 = rotx(2*deg_revolutions);
                left_rot = Rotate2*left;

                left_end = [left_rot(1,app.n_pts+1:end); left_rot(2,app.n_pts+1:end); left_rot(3,app.n_pts+1:end)];
                cir = [left_start, forward, right_rot, backwards, left_end];

                extend_right = right_start_rot(:,end);
                extend_left = left_end(:,end);

                extend_right(1,1) = extend_right(1,1) + over;
                extend_left(1,1) = extend_left(1,1) - over;

                app.circuit = [left_start, forward, right_start_rot, extend_right, right_end_rot, backwards, left_end, extend_left];
                app.begin_hoop = left_start; %used for starting the hoop layer
            end
        end
        
        function cyc = cycle_Generator(app, num_cycles, cir, deg)
            ang = 360 / num_cycles;
            L = length(cir); %changed the functions here

            cyc = zeros(3, num_cycles * L);
            cyc(:, 1:L) = cir; %set first equal to circuit
            
            for j = 1: num_cycles-1
                if app.SphereButton.Value
                    Rotate = rotx(ang);
                else
                    Rotate = rotx(2*deg); %rotate angle by this amount see formula (2*degree rev so that u start where u end)
                end
                cycle_rot = cyc(:,(j-1)*L+1:(j)*L);
                cycle_rot = mtimes(Rotate, cycle_rot);
                cyc(:, j*L+1:(j+1)*L) = cycle_rot;
            end
        end
        
        function lay = layer_Generator(app, num_cycles, cyc)
            offset = 2*(app.w / app.R * 180 / pi); %width angle
            start = 360 / num_cycles;

            n = ceil(start / offset); %number of wraps to get to closest filament 
            offset = start / n;
            L = length(cyc);

            lay = zeros(3,n*L);
            lay(:,1:L) = cyc; %set first equal to cycle

            for j = 1:n-1
                Rotate = rotx(offset); %rotate angle by this amount see formula
                rot_layer = lay(:,(j-1)*L+1:(j)*L); %take the previous layer and store in rot_layer
                rot_layer = mtimes(Rotate, rot_layer); %rotate rot_layer
                lay(:, (j*L)+1:(j+1)*L) = rot_layer; %save the rotated rot_layer in the layer array
            end
        end
        
        function num_cycles = find_cycles(~, deg)
            error = 1;
            n=1;
            while deg >= 180
                deg = deg - 180;
            end
            angle=deg;

            if angle < error || angle > 180 - error
                n = 1;
            else
                while abs(angle)> error
                    angle=angle+deg;
                    if angle>90
                        angle=angle-180;
                    end
                    n=n+1;
                end
            end
            num_cycles=n;
        end
        
        function g_i = G_code(app,  helical_angle)
            if app.SphereButton.Value
                num_cycles = app.WindAngleEditField.Value;
                [cir,~] = circuit_Generator(app, helical_angle);
                cyc = cycle_Generator(app, num_cycles, cir, 0);
                app.cycle = cycle_Generator(app, num_cycles, app.circuit, 0); %for g-code
            else
                [cir, deg_revolutions] = circuit_Generator(app, helical_angle);
                num_cycles = find_cycles(app, deg_revolutions);
                cyc = cycle_Generator(app, num_cycles, cir, deg_revolutions);  

                app.cycle = cycle_Generator(app, num_cycles, app.circuit, deg_revolutions); %for g-code
            end
            lay = layer_Generator(app, num_cycles, cyc);
            app.layer = layer_Generator(app, num_cycles, app.cycle); %for g-code (USE THIS FOR G-CODE EXPORT)

            g_i = {cir, cyc, lay};
            app.g_curr = g_i;% cell array of the circuit, cycle, and layer
        end
        
        function results = tab_empty(app)
            results = isempty(app.UITable.Data);
        end
        
        function [cir_i, cyc_i, lay_i] = map(app, cir_len, cyc_len, lay_len)
            
            cir_i = cir_len * (1 - (100-app.i) / 100);
            cyc_i = cyc_len * (1 - (100-app.i) / 100);
            lay_i = lay_len * (1 - (100-app.i) / 100);
        end
        
        function [phi, r] = calculate_Mandrel_angle(app, g_i) %this function is only for g-code
            %y = g_i{1,3}(2,:); old
            y= g_i(2,:);

            %z = g_i{1,3}(3,:); old
            z = g_i(3,:);

            [phi, r] = cart2pol(y,z);
            phi = mod(phi, 2*pi) * 180 / pi;
            
            for j = 2: length(y)
                if (((phi(j) - phi(j-1)) < 0))
                    phi(j:length(phi))=phi(j:length(phi))+360;
                end

            end
            %phi = phi * 180 / pi;
        end

        
        function plotWeb(app)
            cir = app.g_curr{1,1};
            cyc = app.g_curr{1,2};
            lay = app.g_curr{1,3};

            cir_inc = increase(app, cir, .25);
            cyc_inc = increase(app, cyc, .25);
            lay_inc = increase(app, lay, .25);

            cir_len = length(cir);
            cyc_len = length(cyc);
            lay_len = length(lay);
            [cir_i, cyc_i, lay_i] = map(app, cir_len, cyc_len, lay_len); %calculates point to go up to

            cla(app.UIAxes, 'reset')

            path = app.PathsDropDown.Value;
            switch path
                case 'Circuit'
                    [Th_circuit,R_circuit]=cart2pol(cir_inc(2,1:cir_i),cir_inc(3,1:cir_i));

                    ThR_circuit=Th_circuit+app.w./R_circuit;
                    yR_circuit=R_circuit.*cos(ThR_circuit);
                    zR_circuit=R_circuit.*sin(ThR_circuit);

                    ThL_circuit=Th_circuit-app.w./R_circuit;
                    yL_circuit=R_circuit.*cos(ThL_circuit);
                    zL_circuit=R_circuit.*sin(ThL_circuit);


                    x_meshcir=[cir_inc(1,1:cir_i);cir_inc(1,1:cir_i)];
                    y_meshcir=[yL_circuit;yR_circuit];
                    z_meshcir=[zL_circuit;zR_circuit];

                    hold(app.UIAxes, "all")
                    s1 = surf(app.UIAxes, x_meshcir,y_meshcir,z_meshcir);
                    plot3(app.UIAxes, cir_inc(1,1:cir_i), yR_circuit, zR_circuit, 'k','LineWidth',0.5);
                    plot3(app.UIAxes, cir_inc(1,1:cir_i), yL_circuit, zL_circuit, 'k','LineWidth',0.5);
                    set(s1,'EdgeColor','none')
                    hold(app.UIAxes, "off")

                case 'Cycle'
                    [Th_cycle,R_cycle]=cart2pol(cyc_inc(2,1:cyc_i),cyc_inc(3,1:cyc_i));

                    ThR_cycle=Th_cycle+app.w./R_cycle;
                    yR_cycle=R_cycle.*cos(ThR_cycle); %right surface
                    zR_cycle=R_cycle.*sin(ThR_cycle);
                    cycleR=[cyc_inc(1,1:cyc_i);yR_cycle;zR_cycle];

                    ThL_cycle=Th_cycle-app.w./R_cycle;
                    yL_cycle=R_cycle.*cos(ThL_cycle); %left surface
                    zL_cycle=R_cycle.*sin(ThL_cycle);
                    cycleL=[cyc_inc(1,1:cyc_i);yL_cycle;zL_cycle];

                    x_meshcyc=[cyc_inc(1,1:cyc_i);cyc_inc(1,1:cyc_i)];
                    y_meshcyc=[yL_cycle;yR_cycle];
                    z_meshcyc=[zL_cycle;zR_cycle];

                    hold(app.UIAxes, "all")
                    s2 = surf(app.UIAxes, x_meshcyc,y_meshcyc,z_meshcyc); %surface
                    plot3(app.UIAxes, cycleR(1,:), cycleR(2,:), cycleR(3,:), 'k','LineWidth',0.5); %out line
                    plot3(app.UIAxes, cycleL(1,:), cycleL(2,:), cycleL(3,:), 'k','LineWidth',0.5);
                    set(s2,'EdgeColor','none')
                    hold(app.UIAxes, "off")
                case 'Layer'
                    [Th_full,R_full]=cart2pol(lay_inc(2,1:lay_i),lay_inc(3,1:lay_i));

                    ThR_full=Th_full+app.w./R_full;
                    yR_full=R_full.*cos(ThR_full);
                    zR_full=R_full.*sin(ThR_full);
                    fullR=[lay_inc(1,1:lay_i);yR_full;zR_full];

                    ThL_full=Th_full-app.w./R_full;
                    yL_full=R_full.*cos(ThL_full);
                    zL_full=R_full.*sin(ThL_full);
                    fullL=[lay_inc(1,1:lay_i);yL_full;zL_full];

                    x_meshfull=[lay_inc(1,1:lay_i);lay_inc(1,1:lay_i)];
                    y_meshfull=[yL_full;yR_full];
                    z_meshfull=[zL_full;zR_full];

                    hold(app.UIAxes, "all")
                    s3 = surf(app.UIAxes, x_meshfull,y_meshfull,z_meshfull);
                    plot3(app.UIAxes, fullR(1,:), fullR(2,:), fullR(3,:), 'k','LineWidth',0.1); %out line
                    plot3(app.UIAxes, fullL(1,:), fullL(2,:), fullL(3,:), 'k','LineWidth',0.1);
                    set(s3,'EdgeColor','none')
                    hold(app.UIAxes, "off")
            end
            axis(app.UIAxes, 'equal');
            if app.SphereButton.Value
                xlim(app.UIAxes, [0, app.D])
            else
                xlim(app.UIAxes, [0, app.cyl_Length + app.D])
            end
            ylim(app.UIAxes, [-app.R-1, app.R+1])
            zlim(app.UIAxes, [-app.R-1, app.R+1])
            view(app.UIAxes,-30,30)
        end


        function path_out = increase(~, path_in, inc) %for plotting
            len = length(path_in);
            [T,Ra]=cart2pol(path_in(2,:),path_in(3,:));
            for j = 1: len
                Ra(j) = Ra(j)+inc*j/len; %increase y and z values slightly
            end
            [Y,Z]=pol2cart(T,Ra);
            path_in(2,:)=Y;
            path_in(3,:)=Z;
            path_out = path_in;
        end
        
        function pts2G(app)            
            selectedMold = app.MoldShapeButtonGroup.SelectedObject.Text;
            diam = num2str(app.D);
            inlet_diam = num2str(app.d_0);
            cyl_len = num2str(app.cyl_Length);

            folderName = strcat(selectedMold, '-', diam, '-', inlet_diam, '-', cyl_len);
            mkdir(folderName)

            len = length(app.UITable.Data); %number of layers in the table

            for k = 1:len
                dat = app.UITable.Data{k}; %layer wind angle

                %g_out = app.layer; %reverted last

                if dat == 90 %hoop 
                    hoop(app); %creates hoop layer matrix
                    [g_phi, g_z] = calculate_Mandrel_angle(app, app.hoop_layer);
                    g_z = g_z - app.r_0;
                    g_x = app.hoop_layer(1,:);
                    g_phi = g_phi ./ 360; %convert to 1 unit = 360 degrees


                    filename = [strcat(folderName,'\'), strcat(num2str(k), '-', num2str(dat)), '.txt'];
                    lines = cell(length(g_x)+2, 1); %pre-allocate cell array
                    lines(1,1) = cellstr('G90');
                    lines(2,1) = cellstr('G00');
                    lines(3,1) = cellstr('G20');

                    for j = 4: (length(g_x)+2) %loop through all coordinate positions
                        line = cellstr(['G01 ','X', num2str(g_x(j-2)), '   ', 'Z', num2str(g_z(j-2)), '  ', 'Y', num2str(g_phi(j-2)), ' F750.0']); %G01 is linear interpolation of movement

                        lines(j,1) = line;
                    end
                    writecell(lines, filename);

                    %writematrix(app.hoop_layer, filename,'Delimiter','\t');
                else %helical layer
                    g_out = app.layer;
                    [g_phi, g_z] = calculate_Mandrel_angle(app, g_out);
                    g_z = g_z - app.r_0;
                    g_x = g_out(1,:);
                    g_phi = g_phi ./ 360; %convert to 1 unit = 360 degrees
                    %g_x_mm = g_x .* 25.4; %convert in to mm
                    %g_y_mm = g_y .* 25.4;
                    

                    filename = [strcat(folderName,'\'), strcat(num2str(k), '-', num2str(dat)), '.txt'];
                    lines = cell(length(g_x)+2, 1); %pre-allocate cell array
                    lines(1,1) = cellstr('G90');
                    lines(2,1) = cellstr('G00');
                    lines(3,1) = cellstr('G20');

                    for j = 4: (length(g_x)+2) %loop through all coordinate positions
                        line = cellstr(['G01 ','X', num2str(g_x(j-2)), '   ', 'Z', num2str(g_z(j-2)), '  ', 'Y', num2str(g_phi(j-2)), ' F750.0']); %G01 is linear interpolation of movement

                        lines(j,1) = line;
                    end
                    writecell(lines, filename);
                end
            end
        end

        
        function hoop(app) %this is just the helical wind method with more pts.
            hoop_pts = 5000;
            percent_coverage = .7;
            %app.hoop_layer = zeros(3,hoop_pts + 3*app.n_pts);

            pitch = app.w * percent_coverage; 
            revolutions = app.cyl_Length / pitch; % (7)
            deg_revolutions = revolutions * 360;

            rad_revolutions = deg_revolutions * pi / 180;
            phi_right_back = (270 + deg_revolutions) * pi / 180;

            %forward
            phi_cyl = linspace(pi/2, rad_revolutions + pi/2, hoop_pts); % (9)
            x_cyl = linspace(app.R, app.R + app.cyl_Length, hoop_pts);
            y_cyl = app.R*cos(phi_cyl); % (11)
            z_cyl = app.R*sin(phi_cyl); % (12)

            %backward
            phi_cyl_back = linspace(phi_right_back, phi_right_back + rad_revolutions, hoop_pts);
            x_cyl_back = fliplr(x_cyl);
            y_cyl_back = app.R*cos(phi_cyl_back); % (11)
            z_cyl_back = app.R*sin(phi_cyl_back); % (12)
            
            forward = [x_cyl; y_cyl; z_cyl];
            backward = [x_cyl_back; y_cyl_back; z_cyl_back];
            
            app.hoop_layer = [forward backward];

            %{
            backward = [x_cyl_back; y_cyl_back; z_cyl_back];

            [left, right] = polar_wind(app); %gets the initial polar wrap
            b_hoop_90 = backward(:,hoop_pts/3:hoop_pts*2/3); %comes back for hoop wrap

            app.hoop_layer = left(:,1:app.n_pts);
            app.hoop_layer = [app.hoop_layer, b_hoop_90];
            app.hoop_layer = [app.hoop_layer, b_end(:,app.n_pts*2:end)];

            %app.hoop_layer(:,1:app.n_pts*2) = f_start(:,1:app.n_pts*2);
            %app.hoop_layer(:,app.n_pts*2+1:app.n_pts*2+1001) = b_hoop_90;
            %app.hoop_layer(:, app.n_pts*2+1002+hoop_pts:end) = b_end(:, app.n_pts*2:end);
            %}
        end
        
        function plot_hoop_web(app)
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.D = app.DiameterEditField.Value;
            app.R = app.DiameterEditField.Value / 2;
            app.d_0 = app.InletDiameterEditField.Value;
            app.r_0 = app.InletDiameterEditField.Value /2;
            app.t = app.FilamentThicknessEditField.Value;
            app.w = app.FilamentWidthEditField.Value;
            app.S_F = app.FilamentStrengthEditField.Value;
            app.P = app.PressureEditField.Value;
            app.N = app.SafetyFactorEditField.Value;
            app.ff = app.FrictionFactorEditField.Value;
            app.cyl_Length = app.CylinderLengthEditField.Value;
            app.polar_alpha = asin(app.r_0/app.R);
            app.n_pts = 20;
            app.ProgressSlider.Value = 100;
            app.i = app.ProgressSlider.Value;
        end

        % Selection changed function: MoldShapeButtonGroup
        function MoldShapeButtonGroupSelectionChanged(app, event)
            selectedButton = app.MoldShapeButtonGroup.SelectedObject;
            
        end

        % Callback function: CalculateButton, UITable
        function CalculateButtonPushed(app, event)
            Netting_Analysis(app);
            len = length(app.n_hoop);
            if app.SphereButton.Value
                app.WindAngleEditFieldLabel.Text = 'Cycles';
                app.cyl_Length = 0;
                app.CylinderLengthEditField.Value = 0;
                pop_tab(app);
            else
                app.WindAngleEditFieldLabel.Text = 'Wind Angle';
                for k = 1: len
                    app.DropDown.Items{1,k} = [num2str(app.alpha_list(k)) ', ' num2str(app.n_hoop(k))  ', '  num2str(app.n_helical(k))];
                    app.DropDown.ItemsData{1,k} = [app.alpha_list(k) app.n_hoop(k) app.n_helical(k)];
                end
                plot_Net_Analysis(app);
            end
        end

        % Value changed function: DiameterEditField
        function DiameterEditFieldValueChanged(app, event)
            app.D = app.DiameterEditField.Value;
            app.R = app.D / 2;
            app.polar_alpha = asin(app.r_0/app.R);
        end

        % Value changed function: InletDiameterEditField
        function InletDiameterEditFieldValueChanged(app, event)
            app.d_0 = app.InletDiameterEditField.Value;
            app.r_0 = app.d_0 / 2;
            app.polar_alpha = asin(app.r_0/app.R); %need to update everytime r_0 changes
        end

        % Value changed function: CylinderLengthEditField
        function CylinderLengthEditFieldValueChanged(app, event)
            app.cyl_Length = app.CylinderLengthEditField.Value;
            
        end

        % Value changed function: PressureEditField
        function PressureEditFieldValueChanged(app, event)
            app.P = app.PressureEditField.Value;
            
        end

        % Value changed function: SafetyFactorEditField
        function SafetyFactorEditFieldValueChanged(app, event)
            app.N = app.SafetyFactorEditField.Value;
            
        end

        % Value changed function: FrictionFactorEditField
        function FrictionFactorEditFieldValueChanged(app, event)
            app.ff = app.FrictionFactorEditField.Value;
            
        end

        % Value changed function: FilamentStrengthEditField
        function FilamentStrengthEditFieldValueChanged(app, event)
            app.S_F = app.FilamentStrengthEditField.Value;
            
        end

        % Value changed function: FilamentThicknessEditField
        function FilamentThicknessEditFieldValueChanged(app, event)
            app.t = app.FilamentThicknessEditField.Value;
            
        end

        % Value changed function: FilamentWidthEditField
        function FilamentWidthEditFieldValueChanged(app, event)
            app.w = app.FilamentWidthEditField.Value;
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            %disp(class(value));
            %pop_tab(app)
        end

        % Button pushed function: MoveUpButton
        function MoveUpButtonPushed(app, event)
            if app.selected_layer < 2   
                %already at first element cannot move up anymore
            else
                below_selected = app.UITable.Data(app.selected_layer+1 : end);
                above = app.UITable.Data(app.selected_layer-1);
                selected = app.UITable.Data(app.selected_layer);

                app.UITable.Data(app.selected_layer-1:end) = [selected; above; below_selected];
                app.selected_layer = app.selected_layer - 1;
            end

        end

        % Button down function: MoldShapeButtonGroup
        function MoldShapeButtonGroupButtonDown(app, event)
            
        end

        % Button pushed function: MoveDownButton
        function MoveDownButtonPushed(app, event)
            if app.selected_layer == length(app.UITable.Data)
                %already at bottom element cannot move down anymore
            else
                above_selected = app.UITable.Data(1 : app.selected_layer - 1);
                below = app.UITable.Data(app.selected_layer + 1);
                selected = app.UITable.Data(app.selected_layer);
                app.UITable.Data(1:app.selected_layer + 1) = [above_selected; below; selected];
                app.selected_layer = app.selected_layer + 1;
            end
        end

        % Value changing function: WindAngleSlider
        function WindAngleSliderValueChanging(app, event)
            changingValue = event.Value;
            wind_angle = changingValue * pi / 180;
            app.WindAngleEditField.Value = changingValue;

            if isempty(app.UITable.Data)
            else
                G_code(app, wind_angle);
                plotWeb(app)
            end
        end

        % Value changed function: WindAngleEditField, WindAngleSlider
        function WindAngleSliderValueChanged(app, event)
            value = app.WindAngleEditField.Value;
            wind_angle = value * pi / 180;
            app.WindAngleSlider.Value = value;

            if isempty(app.UITable.Data)                
            else
                G_code(app, wind_angle);
                %plot3D(app)
                plotWeb(app)
            end
        end

        % Value changed function: PathsDropDown
        function PathsDropDownValueChanged(app, event)
            value = app.PathsDropDown.Value;
            if tab_empty(app)
            else
                %plot3D(app)
                plotWeb(app)
            end
        end

        % Value changing function: ProgressSlider
        function ProgressSliderValueChanging(app, event)
            changingValue = event.Value;
            app.i = changingValue;
            plotWeb(app);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            pts2G(app);
            
            %{
            selectedMold = app.MoldShapeButtonGroup.SelectedObject.Text;
            diam = num2str(app.D);
            inlet_diam = num2str(app.d_0);
            cyl_len = num2str(app.cyl_Length);

            folderName = strcat(selectedMold, '-', diam, '-', inlet_diam, '-', cyl_len);
            mkdir(folderName)

            len = length(app.UITable.Data);

            for k = 1:len
                dat = app.UITable.Data{k};
                filename = [strcat(folderName,'\'), strcat(num2str(k), '-', num2str(dat)), '.txt'];
                g_out = G_code(app, dat);
                if dat == 90 %hoop 
                    g_file = [90; 90; 90];
                    writematrix(g_file, filename,'Delimiter','\t')
                  else
                    [g_phi, g_y] = calculate_Mandrel_angle(app, g_out);
                    g_x = g_out{1,3}(1,:);
                    g_file = [g_x; g_y; g_phi]';
                    writematrix(g_file, filename,'Delimiter','\t')
                end
            end
            %}


        end

        % Callback function: AnimatePlotButton, LayersPanel
        function AnimatePlotButtonPushed(app, event)
            cir = app.g_curr{1,1};
            cyc = app.g_curr{1,2};
            lay = app.g_curr{1,3};

            cir_inc = increase(app, cir, .25);
            cyc_inc = increase(app, cyc, .25);
            lay_inc = increase(app, lay, .25);

            cir_len = length(cir);
            cyc_len = length(cyc);
            lay_len = length(lay);

            cla(app.UIAxes, 'reset')


            path = app.PathsDropDown.Value;
            switch path
                case 'Circuit'
                    [Th_circuit,R_circuit]=cart2pol(cir_inc(2,:),cir_inc(3,:));

                    ThR_circuit=Th_circuit+app.w./R_circuit;
                    yR_circuit=R_circuit.*cos(ThR_circuit);
                    zR_circuit=R_circuit.*sin(ThR_circuit);

                    ThL_circuit=Th_circuit-app.w./R_circuit;
                    yL_circuit=R_circuit.*cos(ThL_circuit);
                    zL_circuit=R_circuit.*sin(ThL_circuit);

                    for k = 3: cir_len
                        x_meshcir=[cir_inc(1,1:k);cir_inc(1,1:k)];
                        y_meshcir=[yL_circuit(1,1:k);yR_circuit(1,1:k)];
                        z_meshcir=[zL_circuit(1,1:k);zR_circuit(1,1:k)];

                        hold(app.UIAxes, "all")
                        s2 = surf(app.UIAxes, x_meshcir,y_meshcir,z_meshcir); %surface
                        plot3(app.UIAxes, cir_inc(1,1:k), yR_circuit(1,1:k), zR_circuit(1,1:k), 'k','LineWidth',0.5); %out line
                        plot3(app.UIAxes, cir_inc(1,1:k), yL_circuit(1,1:k), zL_circuit(1,1:k), 'k','LineWidth',0.5);
                        set(s2,'EdgeColor','none')
                        hold(app.UIAxes, "off")

                        axis(app.UIAxes, 'equal');
                        if app.SphereButton.Value
                            xlim(app.UIAxes, [0, app.D])
                        else
                            xlim(app.UIAxes, [0, app.cyl_Length + app.D])
                        end
                        ylim(app.UIAxes, [-app.R-1, app.R+1])
                        zlim(app.UIAxes, [-app.R-1, app.R+1])
                        view(app.UIAxes,-30,30)

                        pause(.01);
                        if k~= cir_len
                            clf
                        end
                    end

                case 'Cycle'
                    [Th_cycle,R_cycle]=cart2pol(cyc_inc(2,:),cyc_inc(3,:));

                    ThR_cycle=Th_cycle+app.w./R_cycle;
                    yR_cycle=R_cycle.*cos(ThR_cycle); %right surface
                    zR_cycle=R_cycle.*sin(ThR_cycle);
                    cycleR=[cyc_inc(1,:);yR_cycle;zR_cycle];

                    ThL_cycle=Th_cycle-app.w./R_cycle;
                    yL_cycle=R_cycle.*cos(ThL_cycle); %left surface
                    zL_cycle=R_cycle.*sin(ThL_cycle);
                    cycleL=[cyc_inc(1,:);yL_cycle;zL_cycle];

                    for k = 3: cyc_len
                        x_meshcyc=[cyc_inc(1,1:k);cyc_inc(1,1:k)];
                        y_meshcyc=[yL_cycle(1,1:k);yR_cycle(1,1:k)];
                        z_meshcyc=[zL_cycle(1,1:k);zR_cycle(1,1:k)];

                        hold(app.UIAxes, "all")

                        s2 = surf(app.UIAxes, x_meshcyc,y_meshcyc,z_meshcyc); %surface
                        plot3(app.UIAxes, cycleR(1,1:k), cycleR(2,1:k), cycleR(3,1:k), 'k','LineWidth',0.5); %out line
                        plot3(app.UIAxes, cycleL(1,1:k), cycleL(2,1:k), cycleL(3,1:k), 'k','LineWidth',0.5);
                        set(s2,'EdgeColor','none')
                   
                        hold(app.UIAxes, "off")
                        axis(app.UIAxes, 'equal');
                        if app.SphereButton.Value
                            xlim(app.UIAxes, [0, app.D])
                        else
                            xlim(app.UIAxes, [0, app.cyl_Length + app.D])
                        end
                        ylim(app.UIAxes, [-app.R-1, app.R+1])
                        zlim(app.UIAxes, [-app.R-1, app.R+1])
                        view(app.UIAxes,-30,30)

 
                        pause(.1);
                        if k~= cyc_len
                            clf
                        end
                    end
                case 'Layer'
                    [Th_full,R_full]=cart2pol(lay_inc(2,1:lay_i),lay_inc(3,1:lay_i));

                    ThR_full=Th_full+app.w./R_full;
                    yR_full=R_full.*cos(ThR_full);
                    zR_full=R_full.*sin(ThR_full);
                    fullR=[lay_inc(1,1:lay_i);yR_full;zR_full];

                    ThL_full=Th_full-app.w./R_full;
                    yL_full=R_full.*cos(ThL_full);
                    zL_full=R_full.*sin(ThL_full);
                    fullL=[lay_inc(1,1:lay_i);yL_full;zL_full];

                    x_meshfull=[lay_inc(1,1:lay_i);lay_inc(1,1:lay_i)];
                    y_meshfull=[yL_full;yR_full];
                    z_meshfull=[zL_full;zR_full];

                    hold(app.UIAxes, "all")
                    s3 = surf(app.UIAxes, x_meshfull,y_meshfull,z_meshfull);
                    plot3(app.UIAxes, fullR(1,:), fullR(2,:), fullR(3,:), 'k','LineWidth',0.1); %out line
                    plot3(app.UIAxes, fullL(1,:), fullL(2,:), fullL(3,:), 'k','LineWidth',0.1);
                    set(s3,'EdgeColor','none')
                    hold(app.UIAxes, "off")
            end
            
            
            %{
for k = 1: length(tt)
    surf(X,Y,Z)
    hold on
    plot3(x(k), y(k), z(k), 'x')
    plot3(x(1:k), y(1:k), z(1:k), 'LineWidth',3);
    xlim([0,x_max])
    axis('equal')
    xlabel('x')
    ylabel('y')
    zlabel('z')
    pause(.1);
    if k~= length(t)
        clf
    end
end
hold off;
%}
        end

        % Clicked callback: DropDown
        function DropDownClicked2(app, event)
            item = event.InteractionInformation.Item;
            app.selected_layer = item;
            pop_tab(app)
        end

        % Double-clicked callback: UITable
        function UITableDoubleClicked2(app, event)
            displayRow = event.InteractionInformation.DisplayRow;
            displayColumn = event.InteractionInformation.DisplayColumn;
            %disp(class(displayRow)) %display row is also a double
            
            helical_angle = app.UITable.Data{displayRow,1};
            if helical_angle == 90.0
                hoop(app);
            else
                G_code(app, helical_angle*pi/180);
            end
            %disp(helical_angle)
            plotWeb(app)
        end

        % Clicked callback: UITable
        function UITableClicked2(app, event)
            displayRow = event.InteractionInformation.DisplayRow;
            displayColumn = event.InteractionInformation.DisplayColumn;
            app.selected_layer = displayRow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'hot')
            app.MoldShapeButtonGroup = uibuttongroup(app.UIFigure);
            app.MoldShapeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MoldShapeButtonGroupSelectionChanged, true);
            app.MoldShapeButtonGroup.Title = 'Mold Shape';
            app.MoldShapeButtonGroup.ButtonDownFcn = createCallbackFcn(app, @MoldShapeButtonGroupButtonDown, true);
            app.SphereButton = uiradiobutton(app.MoldShapeButtonGroup);
            app.SphereButton.Text = 'Sphere';
            app.SphereButton.Value = true;
            app.CylinderButton = uiradiobutton(app.MoldShapeButtonGroup);
            app.CylinderButton.Text = 'Cylinder';
            app.CylinderwithCapsButton = uiradiobutton(app.MoldShapeButtonGroup);
            app.CylinderwithCapsButton.Text = 'Cylinder with Caps';
            app.WindAngleSlider = uislider(app.UIFigure);
            app.WindAngleSlider.Limits = [1 90];
            app.WindAngleSlider.ValueChangedFcn = createCallbackFcn(app, @WindAngleSliderValueChanged, true);
            app.WindAngleSlider.ValueChangingFcn = createCallbackFcn(app, @WindAngleSliderValueChanging, true);
            app.WindAngleSlider.Value = 5;
            app.VesselGeomteryPanel = uipanel(app.UIFigure);
            app.VesselGeomteryPanel.Title = 'Vessel Geomtery';
            app.DiameterEditFieldLabel = uilabel(app.VesselGeomteryPanel);
            app.DiameterEditFieldLabel.Text = 'Diameter';
            app.DiameterEditField = uieditfield(app.VesselGeomteryPanel, 'numeric');
            app.DiameterEditField.ValueChangedFcn = createCallbackFcn(app, @DiameterEditFieldValueChanged, true);
            app.DiameterEditField.Value = 8;
            app.InletDiameterEditFieldLabel = uilabel(app.VesselGeomteryPanel);
            app.InletDiameterEditFieldLabel.Text = 'Inlet Diameter';
            app.InletDiameterEditField = uieditfield(app.VesselGeomteryPanel, 'numeric');
            app.InletDiameterEditField.ValueChangedFcn = createCallbackFcn(app, @InletDiameterEditFieldValueChanged, true);
            app.InletDiameterEditField.Value = 1;
            app.CylinderLengthEditFieldLabel = uilabel(app.VesselGeomteryPanel);
            app.CylinderLengthEditFieldLabel.Text = 'Cylinder Length';
            app.CylinderLengthEditField = uieditfield(app.VesselGeomteryPanel, 'numeric');
            app.CylinderLengthEditField.ValueChangedFcn = createCallbackFcn(app, @CylinderLengthEditFieldValueChanged, true);
            app.CylinderLengthEditField.Value = 16;
            app.DesignParametersPanel = uipanel(app.UIFigure);
            app.DesignParametersPanel.Title = 'Design Parameters';
            app.SafetyFactorEditFieldLabel = uilabel(app.DesignParametersPanel);
            app.SafetyFactorEditFieldLabel.Text = 'Safety Factor';
            app.SafetyFactorEditField = uieditfield(app.DesignParametersPanel, 'numeric');
            app.SafetyFactorEditField.ValueChangedFcn = createCallbackFcn(app, @SafetyFactorEditFieldValueChanged, true);
            app.SafetyFactorEditField.Value = 2;
            app.PressureEditFieldLabel = uilabel(app.DesignParametersPanel);
            app.PressureEditFieldLabel.Text = 'Pressure';
            app.PressureEditField = uieditfield(app.DesignParametersPanel, 'numeric');
            app.PressureEditField.ValueChangedFcn = createCallbackFcn(app, @PressureEditFieldValueChanged, true);
            app.PressureEditField.Value = 1000;
            app.FrictionFactorEditFieldLabel = uilabel(app.DesignParametersPanel);
            app.FrictionFactorEditFieldLabel.Text = 'Friction Factor';
            app.FrictionFactorEditField = uieditfield(app.DesignParametersPanel, 'numeric');
            app.FrictionFactorEditField.ValueChangedFcn = createCallbackFcn(app, @FrictionFactorEditFieldValueChanged, true);
            app.FilamentPropertiesPanel = uipanel(app.UIFigure);
            app.FilamentPropertiesPanel.Title = 'Filament Properties';
            app.FilamentThicknessEditFieldLabel = uilabel(app.FilamentPropertiesPanel);
            app.FilamentThicknessEditFieldLabel.Text = 'Filament Thickness';
            app.FilamentThicknessEditField = uieditfield(app.FilamentPropertiesPanel, 'numeric');
            app.FilamentThicknessEditField.ValueChangedFcn = createCallbackFcn(app, @FilamentThicknessEditFieldValueChanged, true);
            app.FilamentThicknessEditField.Value = 0.004;
            app.FilamentStrengthEditFieldLabel = uilabel(app.FilamentPropertiesPanel);
            app.FilamentStrengthEditFieldLabel.Text = 'Filament Strength';
            app.FilamentStrengthEditField = uieditfield(app.FilamentPropertiesPanel, 'numeric');
            app.FilamentStrengthEditField.ValueChangedFcn = createCallbackFcn(app, @FilamentStrengthEditFieldValueChanged, true);
            app.FilamentStrengthEditField.Value = 710000;
            app.FilamentWidthEditFieldLabel = uilabel(app.FilamentPropertiesPanel);
            app.FilamentWidthEditFieldLabel.Text = 'Filament Width';
            app.FilamentWidthEditField = uieditfield(app.FilamentPropertiesPanel, 'numeric');
            app.FilamentWidthEditField.ValueChangedFcn = createCallbackFcn(app, @FilamentWidthEditFieldValueChanged, true);
            app.FilamentWidthEditField.Value = 0.25;
            app.LayersPanel = uipanel(app.UIFigure);
            app.LayersPanel.Title = 'Layers';
            app.LayersPanel.SizeChangedFcn = createCallbackFcn(app, @AnimatePlotButtonPushed, true);
            app.AddLayerButton = uibutton(app.LayersPanel, 'push');
            app.AddLayerButton.Text = 'Add Layer';
            app.ClearLayerButton = uibutton(app.LayersPanel, 'push');
            app.ClearLayerButton.Text = 'Clear Layer';
            app.ExportButton = uibutton(app.LayersPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.UITable = uitable(app.LayersPanel);
            app.UITable.ColumnName = {'Wind Angle'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.UITable.DoubleClickedFcn = createCallbackFcn(app, @UITableDoubleClicked2, true);
            app.UITable.ClickedFcn = createCallbackFcn(app, @UITableClicked2, true);
            app.MoveUpButton = uibutton(app.LayersPanel, 'push');
            app.MoveUpButton.ButtonPushedFcn = createCallbackFcn(app, @MoveUpButtonPushed, true);
            app.MoveUpButton.Text = 'Move Up';
            app.MoveDownButton = uibutton(app.LayersPanel, 'push');
            app.MoveDownButton.ButtonPushedFcn = createCallbackFcn(app, @MoveDownButtonPushed, true);
            app.MoveDownButton.Text = 'Move Down';
            app.ProgressSlider = uislider(app.UIFigure);
            app.ProgressSlider.MajorTicks = [0 3 6 9 12 15 18 21 24 27 30 33 36 39 42 45 48 51 54 57 60 63 66 69 72 75 78 81 84 87 90 93 96 100];
            app.ProgressSlider.ValueChangingFcn = createCallbackFcn(app, @ProgressSliderValueChanging, true);
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.ClickedFcn = createCallbackFcn(app, @DropDownClicked2, true);
            app.DropDown.Value = {};
            app.PathsDropDownLabel = uilabel(app.UIFigure);
            app.PathsDropDownLabel.Text = 'Paths';
            app.PathsDropDown = uidropdown(app.UIFigure);
            app.PathsDropDown.Items = {'Circuit', 'Cycle', 'Layer'};
            app.PathsDropDown.ValueChangedFcn = createCallbackFcn(app, @PathsDropDownValueChanged, true);
            app.PathsDropDown.Value = 'Circuit';
            app.PathsDropDownLabel_2 = uilabel(app.UIFigure);
            app.PathsDropDownLabel_2.Text = 'Wind Patterns';
            app.WindAngleEditFieldLabel = uilabel(app.UIFigure);
            app.WindAngleEditFieldLabel.Text = 'Wind Angle';
            app.WindAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.WindAngleEditField.ValueChangedFcn = createCallbackFcn(app, @WindAngleSliderValueChanged, true);
            app.WindAngleEditField.Value = 5;
            app.AnimatePlotButton = uibutton(app.UIFigure, 'push');
            app.AnimatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @AnimatePlotButtonPushed, true);
            app.AnimatePlotButton.Text = 'Animate Plot';
            app.MachoWinderXFilamentWindingSoftwareLabel = uilabel(app.UIFigure);
            app.MachoWinderXFilamentWindingSoftwareLabel.Text = 'Macho Winder X - Filament Winding Software';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FilamentWinding

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end